classdef apneaDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        apneaDetectorWindow            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadEDFMenu                    matlab.ui.container.Menu
        DetectApneasandSleepMenu       matlab.ui.container.Menu
        LoadHumanApneaDetectionMenu    matlab.ui.container.Menu
        LoadHumanSleepScoringMenu      matlab.ui.container.Menu
        SaveAnalysisStateMenu          matlab.ui.container.Menu
        LoadPreviouslyAnalyzedEDFMenu  matlab.ui.container.Menu
        ExportDatatomatFileMenu        matlab.ui.container.Menu
        ExportDatatoSpreadsheetMenu    matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        DisplaySettingsMenu            matlab.ui.container.Menu
        UnfilteredSignalYLimitsMenu    matlab.ui.container.Menu
        incrementStd                   matlab.ui.container.Menu
        decrementStd                   matlab.ui.container.Menu
        Default4StdMenu                matlab.ui.container.Menu
        displayEventMarkersMenu        matlab.ui.container.Menu
        RestoreDisplaySettingstoDefaultMenu  matlab.ui.container.Menu
        EventInspectorSettingsMenu     matlab.ui.container.Menu
        ShowPostSighPlusDurationMenu   matlab.ui.container.Menu
        ShowRawSignalOverlayMenu       matlab.ui.container.Menu
        SleepDetectionSettingsMenu     matlab.ui.container.Menu
        EpochDurationMenu              matlab.ui.container.Menu
        SetEpochDurationMenu           matlab.ui.container.Menu
        Default4sMenu                  matlab.ui.container.Menu
        MinimumWakeDurationTimeMenu    matlab.ui.container.Menu
        SignalSmoothingDurationMenu    matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        SignalVariabililityThresholdMultiplierMenu  matlab.ui.container.Menu
        RestoreSleepDetectionSettingstoDefaultMenu  matlab.ui.container.Menu
        UseHumanSleepScoringforApneaDetectionMenu  matlab.ui.container.Menu
        SighDetectionSettingsMenu      matlab.ui.container.Menu
        SighTidalVolumeThresholdMenu   matlab.ui.container.Menu
        StdMenu_3                      matlab.ui.container.Menu
        StdMenu_4                      matlab.ui.container.Menu
        Default4stdMenu_2              matlab.ui.container.Menu
        PostSighPlusDurationMultiplierMenu  matlab.ui.container.Menu
        AdjustPSPMultiplierinEventInspectorBelowMenu  matlab.ui.container.Menu
        ReturntoDefaultMenu_2          matlab.ui.container.Menu
        SighAmplitudeThresholdMenu     matlab.ui.container.Menu
        AdjustSighAmpThreshinEventInspectorBelowMenu  matlab.ui.container.Menu
        ReturntoDefaultMenu            matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        OpenHelpMenu                   matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        httpsgithubcomjessePfammatterapneaDetectorMenu  matlab.ui.container.Menu
        VersionMenu_2                  matlab.ui.container.Menu
        SummaryMenu                    matlab.ui.container.Menu
        OutputApneaStatsMenu           matlab.ui.container.Menu
        filteredSignalWindow           matlab.ui.control.UIAxes
        eventInspectorPlotWindow       matlab.ui.control.UIAxes
        supportingPlot1                matlab.ui.control.UIAxes
        PlotLabel                      matlab.ui.control.Label
        supportingPlot1Dropdown        matlab.ui.control.DropDown
        supportingPlot2                matlab.ui.control.UIAxes
        PlotLabel_2                    matlab.ui.control.Label
        supportingPlot2Dropdown        matlab.ui.control.DropDown
        supportingPlot3                matlab.ui.control.UIAxes
        PlotLabel_3                    matlab.ui.control.Label
        unfilteredSignalWindow         matlab.ui.control.UIAxes
        supportingPlot3Dropdown        matlab.ui.control.DropDown
        breathClusteringPlot           matlab.ui.control.UIAxes
        ManualApneaAdjustButtonGroup   matlab.ui.container.ButtonGroup
        SpontaneousButton              matlab.ui.control.ToggleButton
        PostSighButton                 matlab.ui.control.ToggleButton
        PostSighPlusButton             matlab.ui.control.ToggleButton
        NotEventButton                 matlab.ui.control.ToggleButton
        UnadjustedButton               matlab.ui.control.ToggleButton
        ofTextAreaLabel                matlab.ui.control.Label
        totalNumberOfApneasText        matlab.ui.control.TextArea
        filepathDisplayEDF             matlab.ui.control.TextArea
        SelectPlethChannelDropDown     matlab.ui.control.DropDown
        EventSpinnerLabel              matlab.ui.control.Label
        EventSpinner                   matlab.ui.control.Spinner
        EventNotesDoNotUseReturnKeyTextAreaLabel  matlab.ui.control.Label
        EventNotesDoNotUseReturnKeyTextArea  matlab.ui.control.TextArea
        SessionNotesDoNotUseReturnKeyTextAreaLabel  matlab.ui.control.Label
        SessionNotesDoNotUseReturnKeyTextArea  matlab.ui.control.TextArea
        zoomOutEventInspectorButton    matlab.ui.control.Button
        zoomInEventInspectorButton     matlab.ui.control.Button
        DetectApneasShortcut           matlab.ui.control.Button
        LoadSignalShortcut             matlab.ui.control.Button
        ApneaTypeLabel                 matlab.ui.control.Label
        AutoApneaTypeLabel             matlab.ui.control.Label
        ExploreSignalButton            matlab.ui.control.Button
        filepathDisplaySleepScore      matlab.ui.control.TextArea
        filepathDisplayApneaScore      matlab.ui.control.TextArea
        PSPMultiplierEditFieldLabel    matlab.ui.control.Label
        PSPMultiplierEditField         matlab.ui.control.NumericEditField
        SighAmpThreshEditFieldLabel    matlab.ui.control.Label
        SighAmpThreshEditField         matlab.ui.control.EditField
    end

    
    properties (Access = public)
        
        version = 'ver 0.20';
        
        stdMultiplier = 4; % The multiplier default for setting the yLimits when viewing the unfiltered signal
        defaultSignal = 1;
        filteredSignalYlimScaleFactor = 4; % The multiplier default for setting the yLimits when viewing the filtered signal
        highCutIEI = 6;
        tidalVolCut = 3000;
        highCutAmplitudes = 15;
        downsampleFactor = 20; % this is important for preventing glitches due to Matlab's plotting slowness.
        orangeColor = [0.9,0.5,0.1];
        
        % initialise some variables that don't have starting values
        % THIS MATCH THIS
        filespec;
        header;
        targetSignal;
        breathSignal;
        std_breathSignal;
        fs;
        totalSeconds;
        totalMinutes;
        timeSignal;
        signalSize;
        filteredSignal;
        noiseIndicator;
        output;
        whichEvent;
        eventInspectorBuffer;
        eventInspectorPeaksBuffer;
        zoomInOutFactor;
        humanApneaScore;
        humanApneaScoreFilesep;
        humanSleepScore;
        humanSleepScoreFilesep;
        tp_apneaScores;
        fp_apneaScores;
        fn_apneaScores;
        tn_apneaScores;
        confusionMat_apneaScores;
        tp_sleepScores;
        fp_sleepScores;
        fn_sleepScores;
        tn_sleepScores;
        confusionMat_sleepScores;     
        tempMarkerFiltered;
        tempMarkerCluster;
        tempMarkerEventInspector;
        downsampledTimeSignal;
        downsampledTimeSignalDateTime;
        downsampledBreath;
        downsampledFilteredSignal;
        downsampledIdeal;
        items;
        tempvalue;
        windowwindow;
        humanApneaLoaded;
        humanSleepLoaded;
        apneasDetected;
        useHumanSleepScore;
        tunableParameters;
        PSPMultiplierDefualt;
        startDateAndTime;
        timeSignalDateTime;
        SighAmpThreshDefault;
        
    end
    
    methods (Access = public)
        
        function importFile(app)
            [ app.header,  app.breathSignal] = edfread( app.filespec, 'targetSignals', app.targetSignal); % can add 'targetSignals',targetSignal to only important relevant siganls
            app.std_breathSignal = std( app.breathSignal);
            
        end
        
                
        function plotUnfilteredSignalWindow(app)
            
            % trim down the unfiltered signal to make plotting more efficient.
            app.downsampledTimeSignal = downsample(app.timeSignal, app.downsampleFactor);
            app.downsampledTimeSignalDateTime = downsample(app.timeSignalDateTime, app.downsampleFactor);
            app.downsampledBreath = downsample(app.breathSignal, app.downsampleFactor);
            
            % now plot the unfiltered Signal
            plot(app.unfilteredSignalWindow,  app.downsampledTimeSignalDateTime,  app.downsampledBreath - mean( app.breathSignal), 'k')
            app.unfilteredSignalWindow.YLim = [- app.std_breathSignal *  app.stdMultiplier,  app.std_breathSignal *  app.stdMultiplier];
            app.unfilteredSignalWindow.XLim = [ min(app.downsampledTimeSignalDateTime) max(app.downsampledTimeSignalDateTime)];
            
        end
        
    
        function plotFilteredSignalAndApneas(app)
            
            % downsample the filteredSignal for plotting
            app.downsampledFilteredSignal = downsample(app.filteredSignal, app.downsampleFactor);
            app.downsampledIdeal = downsample(app.output.ideal, app.downsampleFactor);
            app.downsampledTimeSignalDateTime = downsample(app.timeSignalDateTime, app.downsampleFactor); % ### JO Add 200213 (necessary when loading in a scored .mat file)
            
            % plot the filtered signal
            p1 = plot(app.filteredSignalWindow, app.downsampledTimeSignalDateTime, app.downsampledFilteredSignal, 'k');
            hold(app.filteredSignalWindow, 'on')
            
            % plot breath amplitudes
            p3 = plot(app.filteredSignalWindow, app.startDateAndTime + seconds(app.output.peaks / app.fs), app.output.amplitudes, '.c', 'MarkerSize', 10); 
                    
            % plot the breaths with < 60% tidal volume from the median breath, hypopneas
            p5 = plot(app.filteredSignalWindow, app.startDateAndTime + seconds(app.output.peaks(app.output.potentialHypopneas) / app.fs), app.output.amplitudes(app.output.potentialHypopneas), 'b.', 'MarkerSize', 8);
            
            % plot clustered Sighs
            p4 = plot(app.filteredSignalWindow, app.startDateAndTime + seconds(app.output.peaks(app.output.postSighApneaIndex) / app.fs), (app.output.amplitudes(app.output.postSighApneaIndex) * 0) - 4, 'ob');
            
            % adjust the x and y limits
            app.filteredSignalWindow.XLim = [min(app.downsampledTimeSignalDateTime) max(app.downsampledTimeSignalDateTime)];
            app.filteredSignalWindow.YLim = [-6 12];
           
            
            %plotSleepPeriods
            if strcmp(app.UseHumanSleepScoringforApneaDetectionMenu.Checked, 'on')
                
                tempWakeStarts = app.output.humanWakeStarts;
                tempWakeDurations = app.output.humanWakeDurations;
                tempSleepStarts = tempWakeStarts + tempWakeDurations;
                tempSleepEnds = [tempWakeStarts(2:end) - 1, size(app.filteredSignal, 2)];
                tempSleepDurations = tempSleepEnds - tempSleepStarts;
            else
                tempWakeStarts = app.output.automatedWakeStarts;
                tempWakeDurations = app.output.automatedWakeDurations;
                tempSleepStarts = tempWakeStarts + tempWakeDurations;
                tempSleepEnds = [tempWakeStarts(2:end) - 1; size(app.filteredSignal, 2)];
                tempSleepDurations = tempSleepEnds - tempSleepStarts;
            end
            for jj = 1:length(tempSleepStarts)
                temp = tempSleepStarts(jj):tempSleepStarts(jj)+tempSleepDurations(jj);
                plot(app.filteredSignalWindow, app.startDateAndTime + seconds(app.timeSignal(temp)), ones(length(temp), 1) * -4, 'g', 'linewidth', 10)
            end
            awd = plot(app.filteredSignalWindow, app.startDateAndTime + seconds(app.timeSignal(temp)), ones(length(temp), 1) * -4, 'g', 'linewidth', 10); % used for legend

            if app.humanSleepLoaded == 1
                for i = 1:size(app.humanSleepScore, 1)
                    startTime = app.humanSleepScore.StartTime1(i);
                    endTime = app.humanSleepScore.EndTime1(i);
                    temp = startTime:endTime;
                    plot(app.filteredSignalWindow, app.startDateAndTime + seconds(temp), ones(length(temp), 1) * -5, 'k', 'linewidth', 10)
                end
                hsleepleg = plot(app.filteredSignalWindow, app.startDateAndTime + seconds(temp), ones(length(temp), 1) * -5, 'k', 'linewidth', 10); % used for legend
            end
            
            % loop through all of the apneas and plot them on the graph
            for i = 1:size(app.output.apneaDurations, 2)
                temp = app.output.peaks(app.output.apneaIndex(i)):(app.output.peaks(app.output.apneaIndex(i)) + (app.output.apneaDurations(i) * app.fs));
                plot(app.filteredSignalWindow, app.startDateAndTime + seconds(app.timeSignal(temp)), ones(length(temp), 1) * -4, '.r', 'MarkerSize', 10)
            end
            msp = plot(app.filteredSignalWindow, app.startDateAndTime + seconds(app.timeSignal(temp)), ones(length(temp), 1) * -4, '.r', 'MarkerSize', 15); % used for legend
            
            % add the human apnea scores if the user as imported them
            if app.humanApneaLoaded
                for i = 1:size(app.humanApneaScore, 1)
                    startTime = app.humanApneaScore.StartTime(i);
                    endTime = startTime + app.humanApneaScore.Duration(i);
                    temp = startTime:endTime;
                    plot(app.filteredSignalWindow, app.startDateAndTime + seconds(temp), ones(length(temp), 1) * -5, '.', 'Color', app.orangeColor, 'MarkerSize', 10)
                end
                hapnealeg = plot(app.filteredSignalWindow, app.startDateAndTime + seconds(temp), ones(length(temp), 1) * -5, '.', 'Color', app.orangeColor, 'MarkerSize', 15); % used for legend
            end
            
            % plot the legend
            if app.humanApneaLoaded & app.humanSleepLoaded & app.useHumanSleepScore
                legend([p1, p3, msp, hapnealeg, p4, p5, awd, hsleepleg], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Human Sleep', 'Human Sleep'})
            elseif app.humanApneaLoaded & app.humanSleepLoaded
                legend([p1, p3, msp, hapnealeg, p4, p5, awd, hsleepleg], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Machine Sleep', 'Human Sleep'})
            elseif app.humanApneaLoaded & app.useHumanSleepScore
                legend([p1, p3, msp, hapnealeg, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Human Sleep'})
            elseif app.humanApneaLoaded
                legend([p1, p3, msp, hapnealeg, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Machine Sleep'})
            elseif app.humanSleepLoaded & app.useHumanSleepScore
                legend([p1, p3, msp, p4, p5, awd, hsleepleg], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Human Sleep', 'Human Sleep'})
            elseif app.humanSleepLoaded
                legend([p1, p3, msp, p4, p5, awd, hsleepleg], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Machine Sleep', 'Human Sleep'})
            elseif app.useHumanSleepScore
                legend([p1, p3, msp, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Human Sleep'})
            else
                legend([p1, p3, msp, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Machine Sleep'})
            end
                       
            
        end
    
        function plotBreathClustering(app)
            
            % plot the regular breaths first
            temp1 = plot(app.breathClusteringPlot, app.output.iei(app.output.theseAreNormalBreaths), app.output.amplitudes(app.output.theseAreNormalBreaths(1:end)), 'c.', 'MarkerSize', 10); % these are non-apnea related breaths
            hold(app.breathClusteringPlot, 'on')
            grid(app.breathClusteringPlot, 'on')
            
            % adjust the x and y limits
            app.breathClusteringPlot.XLim = [0, app.highCutIEI];
            app.breathClusteringPlot.YLim = [0, app.highCutAmplitudes];
                
            % now plot the rest of the events
            temp2 = plot(app.breathClusteringPlot, app.output.durationsSpontaneousApneas, app.output.amplitudes(app.output.spontaneousApneaIndex), 'r.', 'MarkerSize', 10);
            temp3 = plot(app.breathClusteringPlot, app.output.durationsPostSighApneas, app.output.amplitudes(app.output.postSighApneaIndex), 'r.', 'MarkerSize', 10);
            temp4 = plot(app.breathClusteringPlot, app.output.durationsPostSighApneas, app.output.amplitudes(app.output.postSighApneaIndex), 'bo', 'MarkerSize', 6);
            temp5 = plot(app.breathClusteringPlot, app.output.durationsPostSighPlus, app.output.amplitudes(app.output.postSighPlusApneaIndex), 'r.', 'MarkerSize', 10);

            % plot the legend
            legend(app.breathClusteringPlot, [temp1, temp2, temp4], {'Reg Breaths', 'Apneas', 'Post Sigh Apnea'})

        end


        function plotEventInspector(app)
            
            tempEndDistMult = 2; % this dictates the amount of time shown in the event inspector window.
            
            % set up a few variables
            whichEventIndex = app.output.apneaIndex(app.whichEvent);
            indexForPeaks = whichEventIndex - app.eventInspectorPeaksBuffer :whichEventIndex + app.eventInspectorPeaksBuffer;
            apneaPeakStart = app.output.peaks(whichEventIndex) / app.fs;
            apneaStartInd = find(app.timeSignal == floor(apneaPeakStart));
            if (apneaStartInd - app.eventInspectorBuffer) <= 0
                plotStartInd = 1;
            else
                plotStartInd = apneaStartInd - app.eventInspectorBuffer;
            end
            if plotStartInd + (app.eventInspectorBuffer * tempEndDistMult) > length(app.timeSignal)
                plotEndInd = length(app.timeSignal);
            else
                plotEndInd = plotStartInd + (app.eventInspectorBuffer * tempEndDistMult);
            end
            
            % plot the filtered signal
            p1 = plot(app.eventInspectorPlotWindow, app.downsampledTimeSignalDateTime(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), app.downsampledFilteredSignal(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), 'k');
            hold(app.eventInspectorPlotWindow, "on")
            
            % plot the raw signal in the event inspector if requested
            if strcmp(app.ShowRawSignalOverlayMenu.Checked, 'on')
                temp = app.breathSignal * 2;
                scaledUnfilteredSignal = (temp - mean(temp)) ./ std(temp, 1);
                scaledUnfilteredSignal = downsample(scaledUnfilteredSignal, app.downsampleFactor);
                plot(app.eventInspectorPlotWindow, app.downsampledTimeSignalDateTime(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), scaledUnfilteredSignal(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), 'm');
            end
            
            % plot the ideal signal
            p2 = plot(app.eventInspectorPlotWindow, app.downsampledTimeSignalDateTime(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), app.downsampledIdeal(floor((plotStartInd:plotEndInd) ./ app.downsampleFactor)), 'c');
            
            % plot breath amplitudes
            p3 = plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(indexForPeaks) / app.fs), app.output.amplitudes(indexForPeaks), '.c', 'MarkerSize', 15);
            
            % plot the breaths with < 60% tidal volume from the median breath, hypopneas
            p5 = plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(intersect(indexForPeaks, find(app.output.potentialHypopneas))) / app.fs) , app.output.amplitudes(intersect(indexForPeaks, find(app.output.potentialHypopneas))), 'b.', 'MarkerSize', 15);
            
            % plot clustered Sighs
            p4 = plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.sighStarts / app.fs), app.output.sighAmplitudes, 'ob', 'MarkerSize', 10);
            

            % set the x and y limits for the plot.
            app.eventInspectorPlotWindow.XLim = [app.downsampledTimeSignalDateTime(floor(plotStartInd ./ app.downsampleFactor)) app.downsampledTimeSignalDateTime(floor(plotEndInd ./ app.downsampleFactor))];
            app.eventInspectorPlotWindow.YLim = [-6 10];
            
            %            for i = 1:size(app.output.apneaDurations, 2)
            %   temp = app.output.peaks(app.output.apneaIndex(i)):(app.output.peaks(app.output.apneaIndex(i)) + (app.output.apneaDurations(i) * app.fs));
            %   plot(app.filteredSignalWindow, app.timeSignal(temp), ones(length(temp), 1) * -4, '.r', 'MarkerSize', 8)
            %           end
            
            % plot apneas in the range of the eventInspector
            [tempIntersect, ~, listOfWhichApneas] = intersect(indexForPeaks, app.output.apneaIndex); % index for peaks goes back - 20 breaths from the event in question
            whichPeaks = app.output.peaks(tempIntersect) / app.fs;
            tempDurations = app.output.apneaDurations(listOfWhichApneas); % these are still a smidge short in the plotting window and I don't know why..
            
            for i = 1:length(tempIntersect)
                quantitything = (whichPeaks(i):0.1:whichPeaks(i)+tempDurations(i)); % added this .1 to get plotting straight.. might need to shift with different sample sizes?
                plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(quantitything), ones(length(quantitything), 1) * 5, 'r', 'linewidth', 15)
            end
                        
            % if the display setting is checked then plot the duration of Post Sigh Plus after each Sigh.
            if strcmp(app.ShowPostSighPlusDurationMenu.Checked, 'on')
                for i = 1:length(app.output.sighStarts)
                
                    startTime = app.output.sighStarts(i) / app.fs;
                    endTime = startTime + (app.output.postSighPlusDurationCrit / app.fs);
                    temp = startTime:0.1:endTime;
                    
                    % if they are in the right time window for the current event then plot them
                    if app.startDateAndTime + seconds(startTime) > app.eventInspectorPlotWindow.XLim(1) && app.startDateAndTime + seconds(startTime) < app.eventInspectorPlotWindow.XLim(2)
                            
                        plot(app.eventInspectorPlotWindow,app.startDateAndTime + seconds(temp), ones(length(temp), 1) * app.output.sighAmplitudes(i), '.b', 'linewidth', 15)
                
                    end
                end
                
                
            end
            
            % if human apneas are loaded then plot them in the event inspector
            if app.humanApneaLoaded
                for i = 1:size(app.humanApneaScore, 1)
                    startTime = app.humanApneaScore.StartTime(i);
                    endTime = startTime + app.humanApneaScore.Duration(i);
                    temp = startTime:0.1:endTime;
                    
                    if app.startDateAndTime + seconds(startTime) > app.eventInspectorPlotWindow.XLim(1) && app.startDateAndTime + seconds(startTime) < app.eventInspectorPlotWindow.XLim(2)
                        plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(temp), ones(length(temp), 1) * 6, 'color', app.orangeColor, 'linewidth', 15);
                    end
                    
                end
            end
            
            % now mark where this event occurs on the main plot window and in the clustering batch.
            if strcmp(app.displayEventMarkersMenu.Checked, 'on')
                app.tempMarkerFiltered = plot(app.filteredSignalWindow, app.timeSignalDateTime(app.output.peaks(app.output.apneaIndex(app.whichEvent))), -4, 'or', 'linewidth', 2, 'markersize', 25);
                app.tempMarkerCluster = plot(app.breathClusteringPlot, app.output.iei(app.output.apneaIndex(app.whichEvent)) , app.output.amplitudes(app.output.apneaIndex(app.whichEvent)), 'or', 'linewidth', 2, 'markersize', 25);
            end
            if app.output.typeOfApnea(app.whichEvent) == 1 
                app.tempMarkerEventInspector =  plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(intersect(indexForPeaks, app.output.apneaIndex(app.whichEvent))) / app.fs), 9, 'vr', 'color', '#FFDD15', 'MarkerFaceColor','#FFF1AE','linewidth', 2, 'markersize', 25);
            elseif app.output.typeOfApnea(app.whichEvent) == 2 
                app.tempMarkerEventInspector =  plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(intersect(indexForPeaks, app.output.apneaIndex(app.whichEvent))) / app.fs), 9, 'vr', 'color', '#1A59C6','MarkerFaceColor','#4687F9', 'linewidth', 2, 'markersize', 25);
            elseif app.output.typeOfApnea(app.whichEvent) == 3 
                app.tempMarkerEventInspector =  plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(intersect(indexForPeaks, app.output.apneaIndex(app.whichEvent))) / app.fs), 9, 'vr', 'color', '#AE4FDB', 'MarkerFaceColor','#A16AAD','linewidth', 2, 'markersize', 25);
            end 
            
            % add the apnea type labeling
            if app.output.typeOfApnea(app.whichEvent) == 1
                app.ApneaTypeLabel.Text = 'Spontaneous';
                app.ApneaTypeLabel.FontColor = '#FAAF40'; 
            elseif app.output.typeOfApnea(app.whichEvent) == 2
                app.ApneaTypeLabel.Text = 'Post Sigh';
                app.ApneaTypeLabel.FontColor = '#1A59C6'; 
            elseif app.output.typeOfApnea(app.whichEvent) == 3
                app.ApneaTypeLabel.Text = 'Post Sigh Plus';
                app.ApneaTypeLabel.FontColor = '#AE4FDB'; 
            end
            
            if app.output.manualTypeOfApnea(app.whichEvent) == 0
                app.UnadjustedButton.Value = true;
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 1
                app.SpontaneousButton.Value = true;
                app.tempMarkerEventInspector =  plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(intersect(indexForPeaks, app.output.apneaIndex(app.whichEvent))) / app.fs), 9, 'vr', 'color', '#FFDD15','linewidth', 5, 'markersize', 19);
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 2
                app.PostSighButton.Value = true;
                app.tempMarkerEventInspector =  plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(intersect(indexForPeaks, app.output.apneaIndex(app.whichEvent))) / app.fs), 9, 'vr', 'color', '#1A59C6', 'linewidth', 5, 'markersize', 19);
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 3
                app.PostSighPlusButton.Value = true;
                app.tempMarkerEventInspector =  plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(intersect(indexForPeaks, app.output.apneaIndex(app.whichEvent))) / app.fs), 9, 'vr', 'color', '#AE4FDB','linewidth', 5, 'markersize', 19);
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 4
                app.NotEventButton.Value = true;
                app.tempMarkerEventInspector =  plot(app.eventInspectorPlotWindow, app.startDateAndTime + seconds(app.output.peaks(intersect(indexForPeaks, app.output.apneaIndex(app.whichEvent))) / app.fs), 9, 'vr', 'color', 'k','linewidth', 5, 'markersize', 19);
            end
            
            % load notes data
            app.EventNotesDoNotUseReturnKeyTextArea.Value = app.output.apneaNotes(app.whichEvent);
            
            
        end
    
        function plotSummaryDataInSupportWindow(app, windowNumber)
        
            dropDownValue = app.items(1);
            
            % choose that dynamically allows window selection and inactivates interactions with the plots
            if windowNumber == 1
                temp = app.supportingPlot1;
                app.supportingPlot1Dropdown.Value = dropDownValue;
            elseif windowNumber == 2
                temp = app.supportingPlot2;
                app.supportingPlot2Dropdown.Value = dropDownValue;
            else
                temp = app.supportingPlot3;
                app.supportingPlot3Dropdown.Value = dropDownValue;
  
            end
                        
            % plot numerous text variables of interest
            if app.useHumanSleepScore
                tempTotalHoursSleeping = app.output.humanTotalHoursSleeping;
            else
                tempTotalHoursSleeping = app.output.automatedTotalHoursSleeping;
            end

            text(temp, 0,0.9, strcat("Apnea Threshold (s): ", num2str(app.output.apneaThreshold)));
            text(temp, 0,0.8, strcat("n Spontaneous Apneas: ", num2str(app.output.nSpontaneousApneas)));
            text(temp, 0,0.7, strcat("S Apneas/Hour Sleep: ", num2str(round(app.output.nSpontaneousApneas / tempTotalHoursSleeping, 2))));
            text(temp, 0,0.6, strcat("n Post Sigh Apneas: ", num2str(app.output.nPostSighApneas)));
            text(temp, 0,0.5, strcat("PS Apneas/Hour Sleep: ",   num2str(round(app.output.nPostSighApneas / tempTotalHoursSleeping, 2))));
            text(temp, 0,0.4, strcat("n Post Sigh Plus Apneas: ", num2str(app.output.nPostSighPlus)));
            text(temp, 0,0.3, strcat("PSP Apneas/Hour Sleep: ", num2str(round(app.output.nPostSighPlus / tempTotalHoursSleeping, 2))));
            text(temp, 0,0.2, strcat("Total Sleep Hours: ", num2str(tempTotalHoursSleeping)));
            text(temp, 0,0.1, strcat("n Hypopneas/Hour Sleep: ", num2str(round(app.output.nHypopneas      / tempTotalHoursSleeping, 2))));
            axis(temp, 'off')
         
            
        end
        
         function plotIEIHistInSupportWindow(app, windowNumber)
        
            dropDownValue = app.items(2);
            
            % choose that dynamically allows window selection and inactivates interactions with the plots
            if windowNumber == 1
                temp = app.supportingPlot1;
                app.supportingPlot1Dropdown.Value = dropDownValue;
            elseif windowNumber == 2
                temp = app.supportingPlot2;
                app.supportingPlot2Dropdown.Value = dropDownValue;
            else
                temp = app.supportingPlot3;
                app.supportingPlot3Dropdown.Value = dropDownValue;
            end
            
            %
            axis(temp, 'on')
            tempPlotData = histogram(temp, app.output.iei);
            xlabel(temp, 'Time (s)')
            ylabel(temp, 'Frequency Counts')
            xlim(temp, [0 2])
            hold(temp, 'on')
            
            plot(temp, app.output.apneaThreshold / 2, max(tempPlotData.Values) + max(tempPlotData.Values) * .10, 'vr', 'MarkerSize', 10)
            
         end
         
         function plotAmplitudeHistInSupportWindow(app, windowNumber)
        
            dropDownValue = app.items(3);
            
            % choose that dynamically allows window selection and inactivates interactions with the plots
            if windowNumber == 1
                temp = app.supportingPlot1;
                app.supportingPlot1Dropdown.Value = dropDownValue;
            elseif windowNumber == 2
                temp = app.supportingPlot2;
                app.supportingPlot2Dropdown.Value = dropDownValue;
            else
                temp = app.supportingPlot3;
                app.supportingPlot3Dropdown.Value = dropDownValue;
            end
            
            %
            axis(temp, 'on')
            tempPlotData = histogram(temp, app.output.amplitudes);
            xlabel(temp, 'Norm. Pleth Amplitude')
            ylabel(temp, 'Frequency Counts')
            xlim(temp, [0 app.highCutAmplitudes])
            
            
         end
         

        function plotApneaComparisonInSupportWindow(app, windowNumber)
        
            if app.humanApneaLoaded == 1
                    
                % choose that dynamically allows window selection and inactivates interactions with the plots
                if windowNumber == 1
                    temp = app.supportingPlot1;
                    app.supportingPlot1Dropdown.Value = app.items(4);
                elseif windowNumber == 2
                    temp = app.supportingPlot2;
                    app.supportingPlot2Dropdown.Value = app.items(4);
                else
                    temp = app.supportingPlot3;
                    app.supportingPlot3Dropdown.Value = app.items(4);
                end
            
                % plot numerous text variables of interest
                text(temp, 0,0.8, strcat("True Positives: ", num2str(app.tp_apneaScores)));
                text(temp, 0,0.7, strcat("False Positives: ", num2str(app.fp_apneaScores)));
                text(temp, 0,0.6, strcat("False Negatives: ", num2str(app.fn_apneaScores)));
                text(temp, 0,0.5, strcat("True Negatives: ", num2str(app.tn_apneaScores)));
                axis(temp, 'off')
                
            end
            
        end
        
        function plotSleepComparisonInSupportWindow(app, windowNumber)
            if app.humanSleepLoaded == 1
                    
                % choose that dynamically allows window selection and inactivates interactions with the plots
                if windowNumber == 1
                    temp = app.supportingPlot1;
                    app.supportingPlot1Dropdown.Value = app.items(5);
                elseif windowNumber == 2
                    temp = app.supportingPlot2;
                    app.supportingPlot2Dropdown.Value = app.items(5);
                else
                    temp = app.supportingPlot3;
                    app.supportingPlot3Dropdown.Value = app.items(5);
                end
            
                % plot numerous text variables of interest
                text(temp, 0,0.8, strcat("True Positives: ", num2str(app.tp_sleepScores)));
                text(temp, 0,0.7, strcat("False Positives: ", num2str(app.fp_sleepScores)));
                text(temp, 0,0.6, strcat("False Negatives: ", num2str(app.fn_sleepScores)));
                text(temp, 0,0.5, strcat("True Negatives: ", num2str(app.tn_sleepScores)));
                
                % add TPR (true positive rate, sensitivity)
                text(temp, 0,0.4, strcat("Sensitivity: ", num2str(app.tp_sleepScores / (app.tp_sleepScores + app.fn_sleepScores))));
                text(temp, 0,0.4, strcat("Sensitivity: ", num2str(app.tp_sleepScores / (app.tp_sleepScores + app.fn_sleepScores))));
                
                axis(temp, 'off')
                
            end
        end
        
        function plottingForDropdownMenus(app, tempvalue, windowwindow)
            if tempvalue == 1
                plotSummaryDataInSupportWindow(app, windowwindow)
            elseif tempvalue == 2
                plotIEIHistInSupportWindow(app, windowwindow)
            elseif tempvalue == 3
                plotAmplitudeHistInSupportWindow(app, windowwindow)
            elseif tempvalue == 4
                plotApneaComparisonInSupportWindow(app, windowwindow)
            elseif tempvalue == 5
                plotSleepComparisonInSupportWindow(app, windowwindow)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'DefaultLegendAutoUpdate','off')
            
            % populate list for supporting plot dropdowns
            app.items = {'Summary Data', 'Breath IEI Hist', 'Breath Amp Hist', 'ApneaScore Comp', 'SleepScore Comp'};
            app.supportingPlot1Dropdown.Items = app.items;
            app.supportingPlot2Dropdown.Items = app.items;
            app.supportingPlot3Dropdown.Items = app.items;
            
            % set some variables for the event Inspector
            app.whichEvent = 1;
            app.zoomInOutFactor = 3;
            
            % make plot interactions unavailable
            app.unfilteredSignalWindow.Toolbar.Visible = 'off';
            app.unfilteredSignalWindow.Interactions = [];
            
            app.filteredSignalWindow.Toolbar.Visible = 'off';
            app.filteredSignalWindow.Interactions = []; 
            
            app.breathClusteringPlot.Toolbar.Visible = 'off';
            app.breathClusteringPlot.Interactions = [];
            
            app.eventInspectorPlotWindow.Toolbar.Visible = 'off';
            app.eventInspectorPlotWindow.Interactions = [];
            
            app.supportingPlot1.Toolbar.Visible = 'off';
            app.supportingPlot1.Interactions = []; 
            
            app.supportingPlot2.Toolbar.Visible = 'off';
            app.supportingPlot2.Interactions = [];             
            
            app.supportingPlot2.Toolbar.Visible = 'off';
            app.supportingPlot2.Interactions = []; 
            
            app.useHumanSleepScore = 0;
            
            app.orangeColor = [1 0.5 0];
            
            % set some tunable Parameters
            app.PSPMultiplierDefualt = 10;
            app.PSPMultiplierEditField.Value = app.PSPMultiplierDefualt;
            app.tunableParameters.postSighPlusDurationMultiplier = app.PSPMultiplierDefualt;
            app.SighAmpThreshDefault = 3.5;
            app.SighAmpThreshEditField.Value = num2str(app.SighAmpThreshDefault);
            app.tunableParameters.sighsStdMultiplier = app.SighAmpThreshDefault;


        end

        % Callback function: LoadEDFMenu, LoadSignalShortcut
        function openFile(app, event)
            
            
            app.apneaDetectorWindow.Visible = 'off'; % these are a crude workaround to a matlab glitch on mac. Otherwise uigetfile opens up behind app window.
            [file,  path] = uigetfile('*.edf', 'Pick an EDF file');
            app.apneaDetectorWindow.Visible = 'on'; 

            d = uiprogressdlg(app.apneaDetectorWindow,'Title','Loading EDF File','Indeterminate', 'on');

            app.filespec = strcat( path,  file);
            
            % import the file header
            [ app.header] = edfread( app.filespec);
            app.SelectPlethChannelDropDown.Items =  app.header.label;
            app.SelectPlethChannelDropDown.Value =  app.header.label(app.defaultSignal);
            app.targetSignal = app.SelectPlethChannelDropDown.Value;

            % import the file, this should be an EDF file
            importFile(app)
            
            % enable the PSP multiplier setting now that a file is loaded
            app.PSPMultiplierEditField.Enable = 'on';
            app.PSPMultiplierEditFieldLabel.Enable = 'on';

            % set the sample rate
            app.fs =  app.header.frequency(1); % this sample rate gives shows 120 BPM for a rat which is about what we are looking for and matches the timing from the excel files that Jonathan provided.
            
            % initialize a variable for the event inspector that can't be initialized until sample size is imported.
            
            % these are for display purposes in the event inspector
            app.eventInspectorBuffer = app.fs * 8;
            app.eventInspectorPeaksBuffer = 80;
            
            % establish the date and time
            app.startDateAndTime = datetime(strcat(app.header.startdate, " ", app.header.starttime), 'InputFormat', 'dd.MM.yy HH.mm.ss'); 
            
            % establish the time signal
            app.totalSeconds = length( app.breathSignal) /  app.fs;
            app.totalMinutes =  app.totalSeconds / 60;
            app.timeSignal = 0:( app.totalSeconds/length( app.breathSignal)): app.totalSeconds;
            app.timeSignal =  app.timeSignal(2:end);
            
            % convert timeSignal to datetime
            app.timeSignalDateTime = seconds(app.timeSignal);
            app.timeSignalDateTime.Format = 'hh:mm:ss';
            
            % add the starttime and date to the timeSignal
            app.timeSignalDateTime = app.timeSignalDateTime + app.startDateAndTime;

            % plot the original signal
            plotUnfilteredSignalWindow(app)
                  
            % put a label for the record in the bottom panel
            app.filepathDisplayEDF.Value =  strcat("EDF Filepath: ", app.filespec);
            
            app.SelectPlethChannelDropDown.Visible = 'on';
            app.DetectApneasShortcut.Visible = 'on';
            app.LoadSignalShortcut.Visible = 'off';
            
            close(d)

        end

        % Value changed function: SelectPlethChannelDropDown
        function SelectPlethChannelDropDownValueChanged(app, event)
            
            app.targetSignal = app.SelectPlethChannelDropDown.Value;
            d = uiprogressdlg(app.apneaDetectorWindow,'Title','Loading New Channel','Indeterminate', 'on');
            importFile(app) 
            pause(.5) % this pause keeps a display error from happening -- it's matlabs fault.
            plotUnfilteredSignalWindow(app)
            close(d)
            %plotFilteredSingal(app)
            
            % clear any previously loaded manual sleep scoring and apnea scoring files and any previous analysis.
            if app.humanApneaLoaded == 1
                app.humanApneaScore = [];
                app.humanApneaScoreFilesep = []; 
                app.humanApneaLoaded = 0;
                app.filepathDisplayApneaScore.Value =  strcat("Human Apnea Score Filepath: ", app.humanApneaScoreFilesep); 

            end
            if app.humanSleepLoaded == 1
                app.humanSleepScore = [];
                app.humanSleepScoreFilesep = [];
                app.humanSleepLoaded = 0;
                app.filepathDisplaySleepScore.Value =  strcat("Human Sleep Score Filepath: ", app.humanSleepScoreFilesep); 
            end            
            
            % now clear all of the previous analyses windows
            cla(app.filteredSignalWindow)
            cla(app.eventInspectorPlotWindow)
            cla(app.breathClusteringPlot)
            cla(app.supportingPlot1)
            cla(app.supportingPlot2)
            cla(app.supportingPlot3)
        end

        % Menu selected function: Default4StdMenu
        function Default4StdMenuSelected(app, event)
            app.stdMultiplier = 4;
            plotUnfilteredSignalWindow(app)
        end

        % Menu selected function: incrementStd
        function incrementStdSelected(app, event)
            app.stdMultiplier =  app.stdMultiplier + 1;
            plotUnfilteredSignalWindow(app)
        end

        % Menu selected function: decrementStd
        function decrementStdMenuSelected(app, event)
            app.stdMultiplier =  app.stdMultiplier - 1;
            plotUnfilteredSignalWindow(app)
        end

        % Callback function: DetectApneasShortcut, 
        % DetectApneasandSleepMenu
        function DetectApneasandSleepMenuSelected(app, event)
            
            
            % first clear the plot data
            cla(app.filteredSignalWindow)
            cla(app.eventInspectorPlotWindow)
            cla(app.breathClusteringPlot)
            cla(app.supportingPlot1)
            cla(app.supportingPlot2)
            cla(app.supportingPlot3)
            
            d = uiprogressdlg(app.apneaDetectorWindow,'Title','Detecting Breaths and Apneas','Indeterminate','on');

            tic

            app.filteredSignal = filterAndNormalizePlethSignal(app.breathSignal, app.fs);
           
            % run apnea detection
            if isempty(app.tunableParameters)
                app.output = detectBreathsApneasAndSleepFromPleth(app.breathSignal, app.fs);
            else
                app.output = detectBreathsApneasAndSleepFromPleth(app.breathSignal, app.fs, app.tunableParameters);
            end
            
            % initialize manual apnea change list
            app.output.manualTypeOfApnea = app.output.typeOfApnea * 0; % sets everything to zero for the manual type which means, 'Unadjusted'
            
            % plot everything
            plotFilteredSignalAndApneas(app)
            plotBreathClustering(app)
            plotSummaryDataInSupportWindow(app, 1)
            plotIEIHistInSupportWindow(app, 2)
            plotAmplitudeHistInSupportWindow(app, 3)
           
            % initialize event notes space
            for i = 1:app.output.howManyApneas
                app.output.apneaNotes{i} = '';
            end
            
            % display the explore signal button
            app.ExploreSignalButton.Visible = 'on';
            
            plotEventInspector(app)
            
            app.totalNumberOfApneasText.Value = num2str(app.output.howManyApneas);
            app.EventSpinner.Value = app.whichEvent;
            app.EventSpinner.Limits = [1 app.output.howManyApneas];
    
            % enable some menu items now that we've detected apneas
            app.LoadHumanApneaDetectionMenu.Enable = 'on';
            app.LoadHumanSleepScoringMenu.Enable = 'on';
            app.SaveAnalysisStateMenu.Enable = 'on';
            app.ExportDatatomatFileMenu.Enable = 'on';
            
            toc
            
            app.apneasDetected = 1;
            
            close(d)
            
        end

        % Menu selected function: LoadHumanApneaDetectionMenu
        function LoadHumanApneaDetectionMenuSelected(app, event)
            
            app.apneaDetectorWindow.Visible = 'off'; % these are a crude workaround to a matlab glitch on mac. Otherwise uigetfile opens up behind app window.
            [file,  path] = uigetfile('*.csv', 'Pick an Apnea Scoring File');
            app.apneaDetectorWindow.Visible = 'on';

            d = uiprogressdlg(app.apneaDetectorWindow,'Title','Loading Human Scored Apneas','Indeterminate', 'on');

            % import the human apnea score csv
            app.humanApneaScoreFilesep = strcat( path,  file);
            app.humanApneaScore = importfile_humanApneaScores(app.humanApneaScoreFilesep);
            app.humanApneaScore = app.humanApneaScore(app.humanApneaScore.Duration >= app.output.apneaThreshold, :); % only scores above the same apnea threshold as the computer set.
            
            app.filepathDisplayApneaScore.Value = strcat("Human Apnea Score Filepath: ", app.humanApneaScoreFilesep);

                        
            % compare automated and human apneas
            compareApneaScores(app)

            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            cla(app.filteredSignalWindow)
                        
            % make a tag so we can tell the program that these data were imported
            app.humanApneaLoaded = 1;
            
            % plot everything
            plotFilteredSignalAndApneas(app)
            plotEventInspector(app)
            
            cla(app.supportingPlot3)            
            plotApneaComparisonInSupportWindow(app, 3)
            app.supportingPlot3Dropdown.Value = app.items(4); 
            
            close(d)
        end

        % Menu selected function: LoadHumanSleepScoringMenu
        function LoadHumanSleepScoringMenuSelected(app, event)
            
            app.apneaDetectorWindow.Visible = 'off'; % these are a crude workaround to a matlab glitch on mac. Otherwise uigetfile opens up behind app window.
            [file,  path] = uigetfile('*.csv', 'Please Select a Sleep Score File');
            app.apneaDetectorWindow.Visible = 'on';

            d = uiprogressdlg(app.apneaDetectorWindow,'Title','Loading Human Scored Sleep','Indeterminate', 'on');

            % import the human sleep score csv
            app.humanSleepScoreFilesep = strcat( path,  file);
            app.humanSleepScore = importfile_humanSleepScores(app.humanSleepScoreFilesep);
            
            % put a label for the record in the bottom panel
            app.filepathDisplaySleepScore.Value =  strcat("Human Sleep Score Filepath: ", app.humanSleepScoreFilesep); 
                        
            if app.apneasDetected == 1
            
                % compare automated and human apneas
                compareSleepScores(app)
    
                % delete the old markers for specified events.
                delete(app.tempMarkerFiltered);
                delete(app.tempMarkerCluster);
                delete(app.tempMarkerEventInspector);
    
                % clear the plot window and replot
                cla(app.eventInspectorPlotWindow)
                cla(app.filteredSignalWindow)
                            
                % make a tag so we can tell the program that these data were imported
                app.humanSleepLoaded = 1;
                            
                % plot everything
                plotFilteredSignalAndApneas(app)
                plotEventInspector(app)
                
                cla(app.supportingPlot3)            
                plotSleepComparisonInSupportWindow(app, 3)
                app.supportingPlot3Dropdown.Value = app.items(5);
                
                close(d)
                
            else


            end
        end

        % Menu selected function: LoadPreviouslyAnalyzedEDFMenu
        function LoadPreviouslyAnalyzedEDFMenuSelected(app, event)
            
            app.apneaDetectorWindow.Visible = 'off';
            [file,  path] = uigetfile('*.mat', 'Pick a Scored Apnea Mat File.');
            app.apneaDetectorWindow.Visible = 'on';
             
            d = uiprogressdlg(app.apneaDetectorWindow,'Title','Scored Apnea File','Indeterminate', 'on');
            
            cla(app.filteredSignalWindow)
            cla(app.unfilteredSignalWindow)
            cla(app.eventInspectorPlotWindow)
            cla(app.breathClusteringPlot)
            cla(app.supportingPlot1)
            cla(app.supportingPlot2)
            cla(app.supportingPlot3)
            
            load(strcat(path, file), '-mat');
            
            % now run through the list of objects and add them to the app structure.
            % THIS MATCH THIS
            app.filespec = apneaDetectorOutput.filespec;
            app.header = apneaDetectorOutput.header;
            app.targetSignal = apneaDetectorOutput.targetSignal;
            app.breathSignal = apneaDetectorOutput.breathSignal;
            app.std_breathSignal = apneaDetectorOutput.std_breathSignal;
            app.fs = apneaDetectorOutput.fs;
            app.totalSeconds = apneaDetectorOutput.totalSeconds;
            app.totalMinutes = apneaDetectorOutput.totalMinutes;
            app.timeSignal = apneaDetectorOutput.timeSignal;
            app.signalSize = apneaDetectorOutput.signalSize;
            app.filteredSignal = apneaDetectorOutput.filteredSignal;
            app.noiseIndicator = apneaDetectorOutput.noiseIndicator;
            app.output = apneaDetectorOutput.output;
            app.whichEvent = apneaDetectorOutput.whichEvent;
            app.eventInspectorBuffer = apneaDetectorOutput.eventInspectorBuffer;
            app.eventInspectorPeaksBuffer = apneaDetectorOutput.eventInspectorPeaksBuffer;
            app.zoomInOutFactor = apneaDetectorOutput.zoomInOutFactor;
            app.humanApneaScore = apneaDetectorOutput.humanApneaScore;
            app.humanApneaScoreFilesep = apneaDetectorOutput.humanApneaScoreFilesep;
            app.humanSleepScore = apneaDetectorOutput.humanSleepScore;
            app.humanSleepScoreFilesep = apneaDetectorOutput.humanSleepScoreFilesep;
            app.tp_apneaScores = apneaDetectorOutput.tp_apneaScores;
            app.fp_apneaScores = apneaDetectorOutput.fp_apneaScores;
            app.fn_apneaScores = apneaDetectorOutput.fn_apneaScores;
            app.tn_apneaScores = apneaDetectorOutput.tn_apneaScores;
            app.confusionMat_apneaScores = apneaDetectorOutput.confusionMat_apneaScores;
            app.tp_sleepScores = apneaDetectorOutput.tp_sleepScores;
            app.fp_sleepScores = apneaDetectorOutput.fp_sleepScores;
            app.fn_sleepScores = apneaDetectorOutput.fn_sleepScores;
            app.tn_sleepScores = apneaDetectorOutput.tn_sleepScores;
            app.confusionMat_sleepScores = apneaDetectorOutput.confusionMat_sleepScores;
            app.downsampledTimeSignal = apneaDetectorOutput.downsampledTimeSignal; 
            app.downsampledBreath = apneaDetectorOutput.downsampledBreath; 
            app.downsampledFilteredSignal = apneaDetectorOutput.downsampledFilteredSignal;
            app.downsampledIdeal = apneaDetectorOutput.downsampledIdeal;
            app.humanApneaLoaded = apneaDetectorOutput.humanApneaLoaded;
            app.humanSleepLoaded = apneaDetectorOutput.humanSleepLoaded;
            app.apneasDetected = apneaDetectorOutput.apneasDetected;
            app.useHumanSleepScore = apneaDetectorOutput.useHumanSleepScore;
            app.tunableParameters = apneaDetectorOutput.tunableParameters;
            app.PSPMultiplierDefualt = apneaDetectorOutput.PSPMultiplierDefualt;
            app.SighAmpThreshDefault = apneaDetectorOutput.SighAmpThreshDefault;
            app.startDateAndTime = apneaDetectorOutput.startDateAndTime;
            app.timeSignalDateTime = apneaDetectorOutput.timeSignalDateTime;
                        
            % now plot everything
            plotFilteredSignalAndApneas(app)
            plotUnfilteredSignalWindow(app)
            plotBreathClustering(app)
            plotEventInspector(app)
            plotSummaryDataInSupportWindow(app, 1)
            plotIEIHistInSupportWindow(app, 2)
            plotAmplitudeHistInSupportWindow(app, 3)
            
            % and load a few more settings
            app.SelectPlethChannelDropDown.Items =  app.header.label;
            app.SelectPlethChannelDropDown.Value =  app.header.label(app.defaultSignal);
            app.targetSignal = app.SelectPlethChannelDropDown.Value;
            app.SelectPlethChannelDropDown.Visible = 'on';
            app.ExploreSignalButton.Visible = 'on';
            app.DetectApneasShortcut.Visible = 'on';
            app.LoadSignalShortcut.Visible = 'off';
            
            app.filepathDisplayEDF.Value =  strcat("EDF Filepath: ", app.filespec);
            
            if app.humanSleepScoreFilesep
                app.filepathDisplaySleepScore.Value =  strcat("Human Sleep Score Filepath: ", app.humanSleepScoreFilesep);
            end
            if app.humanApneaScoreFilesep
                app.filepathDisplayApneaScore.Value = strcat("Human Apnea Score Filepath: ", app.humanApneaScoreFilesep);
            end

            app.totalNumberOfApneasText.Value = num2str(app.output.howManyApneas);
            app.EventSpinner.Value = app.whichEvent;
            app.EventSpinner.Limits = [1 app.output.howManyApneas];
    
            % enable some menu items now that we've detected apneas
            app.LoadHumanApneaDetectionMenu.Enable = 'on';
            app.LoadHumanSleepScoringMenu.Enable = 'on';
            app.SaveAnalysisStateMenu.Enable = 'on';
            app.ExportDatatomatFileMenu.Enable = 'on';
                      
            close(d)
            
        end

        % Value changed function: EventSpinner
        function EventSpinnerValueChanged(app, event)
            
            app.whichEvent = app.EventSpinner.Value;
            
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);
            
        end

        % Selection changed function: ManualApneaAdjustButtonGroup
        function ManualApneaAdjustButtonGroupSelectionChanged(app, event)
            temp = app.ManualApneaAdjustButtonGroup.SelectedObject;
            
            if strcmp(temp.Text, 'Unadjusted')
                app.output.manualTypeOfApnea(app.whichEvent) = 0;
            elseif strcmp(temp.Text, 'Spontaneous')
                app.output.manualTypeOfApnea(app.whichEvent) = 1;
            elseif strcmp(temp.Text, 'Post Sigh')
                app.output.manualTypeOfApnea(app.whichEvent) = 2;
            elseif strcmp(temp.Text, 'Post Sigh Plus')
                app.output.manualTypeOfApnea(app.whichEvent) = 3;
            elseif strcmp(temp.Text, 'Not Event')
                app.output.manualTypeOfApnea(app.whichEvent) = 4;
            end

        end

        % Value changed function: 
        % EventNotesDoNotUseReturnKeyTextArea
        function EventNotesDoNotUseReturnKeyTextAreaValueChanged(app, event)
            app.output.apneaNotes(app.whichEvent) = app.EventNotesDoNotUseReturnKeyTextArea.Value;
            
        end

        % Value changed function: 
        % SessionNotesDoNotUseReturnKeyTextArea
        function SessionNotesDoNotUseReturnKeyTextAreaValueChanged(app, event)
            app.output.sessionNotes = app.SessionNotesDoNotUseReturnKeyTextArea.Value;
            
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            closereq
        end

        % Callback function
        function RawSignalButtonValueChanged(app, event)
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);
        end

        % Value changed function: supportingPlot2Dropdown
        function supportingPlot2DropdownValueChanged(app, event)
            cla(app.supportingPlot2)
            app.tempvalue = app.supportingPlot2Dropdown.Value;
            app.tempvalue = find(strcmp(app.items, app.tempvalue));
            app.windowwindow = 2;
            plottingForDropdownMenus(app, app.tempvalue, app.windowwindow)
        end

        % Value changed function: supportingPlot1Dropdown
        function supportingPlot1DropdownValueChanged(app, event)
            cla(app.supportingPlot1)
            app.tempvalue = app.supportingPlot1Dropdown.Value;
            app.tempvalue = find(strcmp(app.items, app.tempvalue));
            app.windowwindow = 1;
            plottingForDropdownMenus(app, app.tempvalue, app.windowwindow)
        end

        % Value changed function: supportingPlot3Dropdown
        function supportingPlot3DropdownValueChanged(app, event)
            cla(app.supportingPlot3)
            app.tempvalue = app.supportingPlot3Dropdown.Value;
            app.tempvalue = find(strcmp(app.items, app.tempvalue));
            app.windowwindow = 3;
            plottingForDropdownMenus(app, app.tempvalue, app.windowwindow)
        end

        % Button pushed function: zoomOutEventInspectorButton
        function zoomOutEventInspectorButtonPushed(app, event)
            app.eventInspectorBuffer = app.eventInspectorBuffer + ( app.fs * app.zoomInOutFactor);
            app.eventInspectorPeaksBuffer = app.eventInspectorPeaksBuffer + (app.zoomInOutFactor * 10);
            
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);
        end

        % Button pushed function: zoomInEventInspectorButton
        function zoomInEventInspectorButtonPushed(app, event)
            if app.eventInspectorBuffer > app.fs * 5
                
                % delete the old markers for specified events.
                delete(app.tempMarkerFiltered);
                delete(app.tempMarkerCluster);
                delete(app.tempMarkerEventInspector);
    
                % clear the plot window and replot
                cla(app.eventInspectorPlotWindow)
                plotEventInspector(app);
            
                app.eventInspectorBuffer = app.eventInspectorBuffer - ( app.fs * app.zoomInOutFactor);
                app.eventInspectorPeaksBuffer = app.eventInspectorPeaksBuffer - (app.zoomInOutFactor * 10);
            end
        end

        % Button pushed function: ExploreSignalButton
        function ExploreSignalButtonPushed(app, event)
            figure('name', 'Explore Signal Popout', 'Units', 'inches', 'Position', [2 2 20 6] );
            
            % plot the filtered signal
            p1 = plot(app.downsampledTimeSignalDateTime, app.downsampledFilteredSignal, 'k');
            hold on;
  
            % plot breath amplitudes
            p3 = plot(app.startDateAndTime + seconds(app.output.peaks / app.fs), app.output.amplitudes, '.c', 'MarkerSize', 10);
                    
            % plot the breaths with < 60% tidal volume from the median breath, hypopneas
            p5 = plot(app.startDateAndTime + seconds(app.output.peaks(app.output.potentialHypopneas) / app.fs), app.output.amplitudes(app.output.potentialHypopneas), 'b.', 'MarkerSize', 15);
            
            % plot clustered Sighs
            p4 = plot(app.startDateAndTime + seconds(app.output.peaks(app.output.postSighApneaIndex) / app.fs), (app.output.amplitudes(app.output.postSighApneaIndex) * 0) - 4, 'ob');

            % adjust the x and y limits
            xlim([min(app.downsampledTimeSignalDateTime)  max(app.downsampledTimeSignalDateTime)]);
            ylim([-app.highCutAmplitudes, app.highCutAmplitudes]);
            
            % add labels
            xlabel('Time (s)')
            ylabel('Norm. Pleth Amplitude')
           
            
            %plotSleepPeriods 
            if app.useHumanSleepScore
                tempWakeStarts = app.output.humanWakeStarts;
                tempWakeDurations = app.output.humanWakeDurations;
                tempSleepStarts = tempWakeStarts + tempWakeDurations;
                tempSleepEnds = [tempWakeStarts(2:end) - 1, size(app.filteredSignal, 2)];
                tempSleepDurations = tempSleepEnds - tempSleepStarts;
            else
                tempWakeStarts = app.output.automatedWakeStarts;
                tempWakeDurations = app.output.automatedWakeDurations;
                tempSleepStarts = tempWakeStarts + tempWakeDurations;
                tempSleepEnds = [tempWakeStarts(2:end) - 1; size(app.filteredSignal, 2)];
                tempSleepDurations = tempSleepEnds - tempSleepStarts;
            end
                        
               for jj = 1:length(tempSleepStarts)
                temp = tempSleepStarts(jj):tempSleepStarts(jj)+tempSleepDurations(jj);
                plot(app.filteredSignalWindow, app.startDateAndTime + seconds(app.timeSignal(temp)), ones(length(temp), 1) * -4, 'g', 'linewidth', 10)
               end
            awd = plot(app.startDateAndTime + seconds(app.timeSignal(temp)), ones(length(temp), 1) * -4, 'g', 'linewidth', 10); % used for legend

            if app.humanSleepLoaded == 1
                for i = 1:size(app.humanSleepScore, 1)
                    startTime = app.humanSleepScore.StartTime1(i);
                    endTime = app.humanSleepScore.EndTime1(i);
                    temp = startTime:endTime;
                    plot(app.startDateAndTime + seconds(temp), ones(length(temp), 1) * -5, 'k', 'linewidth', 10)
                end
                hsleepleg = plot(app.startDateAndTime + seconds(temp), ones(length(temp), 1) * -5, 'k', 'linewidth', 10); % used for legend
            end
            
             % loop through all of the apneas and plot them on the graph
            for i = 1:size(app.output.apneaDurations, 2)
                temp = app.output.peaks(app.output.apneaIndex(i)):(app.output.peaks(app.output.apneaIndex(i)) + (app.output.apneaDurations(i) * app.fs));
                plot(app.startDateAndTime + seconds(app.timeSignal(temp)), ones(length(temp), 1) * -4, '.r', 'MarkerSize', 10)
            end
            msp = plot(app.startDateAndTime + seconds(app.timeSignal(temp)), ones(length(temp), 1) * -4, '.r', 'MarkerSize', 15); % used for legend
            
            
            % add the human apnea scores if the user as imported them
            if app.humanApneaLoaded
                for i = 1:size(app.humanApneaScore, 1)
                    startTime = app.humanApneaScore.StartTime(i);
                    endTime = startTime + app.humanApneaScore.Duration(i);
                    temp = startTime:endTime;
                    plot(app.startDateAndTime + seconds(temp), ones(length(temp), 1) * -5, '.', 'color', app.orangeColor, 'MarkerSize', 10) % ### JO change 200213, deleted " 'MarkerType " (fix plotting error w/ “Explore Signal”)
                end
                hapnealeg = plot(app.startDateAndTime + seconds(temp), ones(length(temp), 1) * -5, '.', 'color', app.orangeColor, 'MarkerSize', 15); % used for legend
            end
            
            % plot the legend
            if app.humanApneaLoaded & app.humanSleepLoaded & app.useHumanSleepScore
                legend([p1, p3, msp, hapnealeg, p4, p5, awd, hsleepleg], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Human Sleep', 'Human Sleep'})
            elseif app.humanApneaLoaded & app.humanSleepLoaded
                legend([p1, p3, msp, hapnealeg, p4, p5, awd, hsleepleg], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Machine Sleep', 'Human Sleep'})
            elseif app.humanApneaLoaded & app.useHumanSleepScore
                legend([p1, p3, msp, hapnealeg, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Human Sleep'})
            elseif app.humanApneaLoaded
                legend([p1, p3, msp, hapnealeg, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Human Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Machine Sleep'})
            elseif app.humanSleepLoaded & app.useHumanSleepScore
                legend([p1, p3, msp, p4, p5, awd, hsleepleg], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Human Sleep', 'Human Sleep'})
            elseif app.humanSleepLoaded
                legend([p1, p3, msp, p4, p5, awd, hsleepleg], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Machine Sleep', 'Human Sleep'})
            elseif app.useHumanSleepScore
                legend([p1, p3, msp, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Human Sleep'})
            else
                legend([p1, p3, msp, p4, p5, awd], {'Filtered Signal', 'Reg Breaths', 'Machine Apneas', 'Post Sigh Apnea', 'Hypopneas', 'Machine Sleep'})
            end
        end

        % Menu selected function: displayEventMarkersMenu
        function displayEventMarkersMenuSelected(app, event)
        
            if strcmp(app.displayEventMarkersMenu.Checked, 'on')
                app.displayEventMarkersMenu.Checked = 'off';
            else
                app.displayEventMarkersMenu.Checked = 'on';
            end
            
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            
            plotEventInspector(app)
            plotFilteredSignalAndApneas(app)
            plotBreathClustering(app)
        
        end

        % Menu selected function: 
        % httpsgithubcomjessePfammatterapneaDetectorMenu
        function httpsgithubcomjessePfammatterapneaDetectorMenuSelected(app, event)
            
        end

        % Menu selected function: 
        % UseHumanSleepScoringforApneaDetectionMenu
        function UseHumanSleepScoringforApneaDetectionMenuSelected(app, event)
        
                
            % display dialog box warning
            selection = uiconfirm(app.apneaDetectorWindow, 'Warning! Changing this value will NOT restart the analysis. Please select Detect Apneas after you have changed all appropriate settings. Do you want to proceed?', 'Change PSP Value?');
            if strcmp(selection, 'OK') 
                
                % change the menu check mark
                if strcmp(app.UseHumanSleepScoringforApneaDetectionMenu.Checked, 'on')
                    app.UseHumanSleepScoringforApneaDetectionMenu.Checked = 'off';
                    
                else
                    app.UseHumanSleepScoringforApneaDetectionMenu.Checked = 'on';
                    app.LoadHumanSleepScoringMenu.Enable = 'on';
                end
                
                % change the internal marker label and load the human scoring
                if strcmp(app.UseHumanSleepScoringforApneaDetectionMenu.Checked, 'on')
                    app.useHumanSleepScore = 1;
                    LoadHumanSleepScoringMenuSelected(app)
                    app.tunableParameters.humanSleepScore = app.humanSleepScore;
                else
                    app.useHumanSleepScore = 0;
                    if isfield(app.tunableParameters, 'humanSleepScore')
                        app.tunableParameters = rmfield(app.tunableParameters, 'humanSleepScore');
                    end
                end
            end
            
            
        end

        % Menu selected function: ExportDatatoSpreadsheetMenu
        function ExportDatatoSpreadsheetMenuSelected(app, event)
                     
        
            % save the detection parameters
            startDateAndTime = app.startDateAndTime;
            highThreshMultiplier = app.output.detectionParameters.highThreshMultiplier;
            lowThreshMultiplier = app.output.detectionParameters.lowThreshMultiplier;
            hypopneaThresh = app.output.detectionParameters.hypopneaThresh;
            normalBreath = app.output.detectionParameters.normalBreath; 
            apneaThresholdMultiplier = app.output.detectionParameters.apneaThresholdMultiplier; 
            breathGluerParam = app.output.detectionParameters.breathGluerParam; 
            lowAmplitudesBreathStdMultiplier = app.output.detectionParameters.lowAmplitudesBreathStdMultiplier; 
            breathCutoff = app.output.detectionParameters.breathCutoff; 
            breathRateSecsLowEnd = app.output.detectionParameters.breathRateSecsLowEnd;
            breathRateSecsHighEnd = app.output.detectionParameters.breathRateSecsHighEnd; 
            nStdsFromIIEContinuous = app.output.detectionParameters.nStdsFromIIEContinuous;
            
            postSighPlusDurationMultiplier = app.output.detectionParameters.postSighPlusDurationMultiplier; 
            sighsStdMultiplier = app.output.detectionParameters.sighsStdMultiplier;
            %sighTidalVolumeMultFactor = app.output.detectionParameters.sighTidalVolumeMultFactor;
            sighArtifactDivisionFactor = app.output.detectionParameters.sighArtifactDivisionFactor;
            
            wakeDurationsCutoff = app.output.detectionParameters.wakeDurationsCutoff;
            smoothDuration = app.output.detectionParameters.smoothDuration;
            nSecs = app.output.detectionParameters.nSecs;
            signalVariabilityThreshMultiplier = app.output.detectionParameters.signalVariabilityThreshMultiplier;
            removeFromSleepRecord = app.output.detectionParameters.removeFromSleepRecord;
            
            T = table(startDateAndTime, highThreshMultiplier, lowThreshMultiplier, hypopneaThresh, normalBreath, apneaThresholdMultiplier, breathGluerParam, lowAmplitudesBreathStdMultiplier, breathCutoff, breathRateSecsLowEnd, breathRateSecsHighEnd, nStdsFromIIEContinuous, postSighPlusDurationMultiplier, sighsStdMultiplier, sighArtifactDivisionFactor, wakeDurationsCutoff, smoothDuration, nSecs, signalVariabilityThreshMultiplier, removeFromSleepRecord);
            
            [recordPath, recordName] = fileparts(app.filespec);
            filename = strcat(recordPath, filesep, recordName, '_', app.targetSignal, "_apneaDetection_", strrep(app.version, '.', '_'), '.xlsx');
            [recordName, recordPath] = uiputfile(filename);
            filename = strcat(recordPath, recordName);
            
            % remove an old file if one had been made before
            if exist(filename,'file')
                delete(filename)
            end
            
            writetable(T,filename,'Sheet', 'detectionParameters')
            
            % just save some basic single numbers
            if app.useHumanSleepScore
                tempTotalHoursSleeping = app.output.humanTotalHoursSleeping;
            else
                tempTotalHoursSleeping = app.output.automatedTotalHoursSleeping;
            end
                  
            filefilename = string(app.filespec);
            chamber = string(app.targetSignal);
            scriptVersion = string(app.version);
            dateExported = string(date);
            if app.useHumanSleepScore == 1
                sleepScoringType = "human";
            else
                sleepScoringType = "machine";
            end
            
            apneaThreshold = app.output.apneaThreshold;
            nSpontaneousApneas = app.output.nSpontaneousApneas;
            nSpontaneousApneasPerHrSleep = round(app.output.nSpontaneousApneas / tempTotalHoursSleeping, 2);
            nPostSighApneas = app.output.nPostSighApneas;
            nPostSighApneasPerHrSleep = round(app.output.nPostSighApneas / tempTotalHoursSleeping, 2);
            nPostSighPlus = app.output.nPostSighPlus;
            nPostSighPlusPerHrSleep = round(app.output.nPostSighPlus / tempTotalHoursSleeping, 2);
            totalSleepHours = tempTotalHoursSleeping;
            nHypopneas = app.output.nHypopneas;
            nHypopneasPerHrSleep = round(app.output.nHypopneas / tempTotalHoursSleeping, 2);
            meanTidalVolume = app.output.meanTidalVolume;
                        
            T = table(filefilename, chamber, scriptVersion, dateExported, sleepScoringType, apneaThreshold, nSpontaneousApneas, nSpontaneousApneasPerHrSleep, nPostSighApneas, nPostSighApneasPerHrSleep, nPostSighPlus, nPostSighPlusPerHrSleep, totalSleepHours, nHypopneas, nHypopneasPerHrSleep, meanTidalVolume);
            
            writetable(T,filename,'Sheet', 'summaryStatistics')
            
            % now save some more complicated output based on every apnea
            apneaStarts = app.output.apneaStarts';
            apneaDurations = app.output.apneaDurations';
            apneaClassificaton = app.output.typeOfApnea';
            humanApneaAdjustments = app.output.manualTypeOfApnea';
            apneaNotes = app.output.apneaNotes';
            timeSincePreviousSigh = app.output.apneaTimeFromPreviousSigh';

            T = table(apneaStarts, apneaDurations, apneaClassificaton, timeSincePreviousSigh, humanApneaAdjustments, apneaNotes);
          
            writetable(T,filename,'Sheet', 'everyApneaData')
            
            % now save some breath by breath information
            breathStarts = app.output.starts';
            breathEnds = app.output.ends';
            breathTidalVolumes = app.output.tidalVolume';
            sighIndex = app.output.sighInd';
            potentialHypopneas = app.output.potentialHypopneas';
            %signalVariability_forSleepThresh = app.output.signalVariability; before this works I have to convert it to a breath by breath number and right now it goes epoch by epochn (or nSecs)
            potentialApneas = [app.output.theseAreMissedBreaths, 0]'; % this is appended with a 0 because in order to include it in the same table, because iei and potential apneas area function of 2 breaths each thus the record is one less in length than the starts and ends signals
            interEventIntervals = [app.output.iei, 0]'; 
            
            T = table(breathStarts, breathEnds, breathTidalVolumes, sighIndex, potentialHypopneas, potentialApneas, interEventIntervals);
            [recordPath, recordName] = fileparts(app.filespec);
            writetable(T,filename,'Sheet', 'everyBreathData')
            
            % add the automated sleep scoring information
            automatedWakeStarts = app.output.automatedWakeStarts;
            automatedWakeEnds = app.output.automatedWakeEnds';
            automatedWakeDurations = app.output.automatedWakeDurations;
            
            T = table(automatedWakeStarts, automatedWakeEnds, automatedWakeDurations);
            writetable(T,filename,'Sheet', 'sleepScoringData')
            
            % save data on human marked apneas if they were loaded
            if app.humanApneaLoaded
                writetable(app.humanApneaScore, filename, 'Sheet', 'humanApneaScoring')
                apneaTruePositives = app.tp_apneaScores;
                apneaTrueNegatives = app.tn_apneaScores;
                apneaFalsePositives = app.fp_apneaScores;
                apneaFalseNegatives = app.fn_apneaScores;
                T = table(apneaTruePositives, apneaTrueNegatives, apneaFalsePositives, apneaFalseNegatives);
                writetable(T,filename,'Sheet', 'apneaScoringTruthTable')
            end
                                    
            % add some summary data for sleep scoring
            if app.humanSleepLoaded
                writetable(app.humanSleepScore, filename, 'Sheet','humanSleepScoring')
                sleepTruePositives = app.tp_sleepScores;
                sleepTrueNegatives = app.tn_sleepScores;
                sleepFalsePositives = app.fp_sleepScores;
                sleepFalseNegatives = app.fn_sleepScores;
                T = table(sleepTruePositives, sleepTrueNegatives, sleepFalsePositives, sleepFalseNegatives);
                writetable(T,filename,'Sheet','sleepScoringTruthTable')
            end
            
            display(strcat("File Exported Succesfully! Path: ", filename))


        end

        % Menu selected function: ExportDatatomatFileMenu
        function ExportDatatomatFileMenuSelected(app, event)
            app.output.version = app.version;
            app.output.dateProcessed = date;
            
            % THIS MATCH THIS
            apneaDetectorOutput.filespec = app.filespec;
            apneaDetectorOutput.header = app.header;
            apneaDetectorOutput.targetSignal = app.targetSignal;
            apneaDetectorOutput.breathSignal = app.breathSignal;
            apneaDetectorOutput.std_breathSignal = app.std_breathSignal;
            apneaDetectorOutput.fs = app.fs;
            apneaDetectorOutput.totalSeconds = app.totalSeconds;
            apneaDetectorOutput.totalMinutes = app.totalMinutes;
            apneaDetectorOutput.timeSignal = app.timeSignal;
            apneaDetectorOutput.signalSize = app.signalSize;
            apneaDetectorOutput.filteredSignal = app.filteredSignal;
            apneaDetectorOutput.noiseIndicator = app.noiseIndicator;
            apneaDetectorOutput.output = app.output;
            apneaDetectorOutput.whichEvent = app.whichEvent;
            apneaDetectorOutput.eventInspectorBuffer = app.eventInspectorBuffer;
            apneaDetectorOutput.eventInspectorPeaksBuffer = app.eventInspectorPeaksBuffer;
            apneaDetectorOutput.zoomInOutFactor = app.zoomInOutFactor;
            apneaDetectorOutput.humanApneaScore = app.humanApneaScore;
            apneaDetectorOutput.humanApneaScoreFilesep = app.humanApneaScoreFilesep;
            apneaDetectorOutput.humanSleepScore = app.humanSleepScore;
            apneaDetectorOutput.humanSleepScoreFilesep = app.humanSleepScoreFilesep;
            apneaDetectorOutput.tp_apneaScores = app.tp_apneaScores;
            apneaDetectorOutput.fp_apneaScores = app.fp_apneaScores;
            apneaDetectorOutput.fn_apneaScores = app.fn_apneaScores;
            apneaDetectorOutput.tn_apneaScores = app.tn_apneaScores;
            apneaDetectorOutput.confusionMat_apneaScores = app.confusionMat_apneaScores;
            apneaDetectorOutput.tp_sleepScores = app.tp_sleepScores;
            apneaDetectorOutput.fp_sleepScores = app.fp_sleepScores;
            apneaDetectorOutput.fn_sleepScores = app.fn_sleepScores;
            apneaDetectorOutput.tn_sleepScores = app.tn_sleepScores;
            apneaDetectorOutput.confusionMat_sleepScores = app.confusionMat_sleepScores;
            apneaDetectorOutput.downsampledTimeSignal = app.downsampledTimeSignal; 
            apneaDetectorOutput.downsampledBreath = app.downsampledBreath; 
            apneaDetectorOutput.downsampledFilteredSignal = app.downsampledFilteredSignal;
            apneaDetectorOutput.downsampledIdeal = app.downsampledIdeal;
            apneaDetectorOutput.humanApneaLoaded = app.humanApneaLoaded;
            apneaDetectorOutput.humanSleepLoaded = app.humanSleepLoaded;
            apneaDetectorOutput.apneasDetected = app.apneasDetected;
            apneaDetectorOutput.useHumanSleepScore = app.useHumanSleepScore;
            apneaDetectorOutput.tunableParameters = app.tunableParameters;
            apneaDetectorOutput.PSPMultiplierDefualt = app.PSPMultiplierDefualt;
            apneaDetectorOutput.SighAmpThreshDefault = app.SighAmpThreshDefault;
            apneaDetectorOutput.startDateAndTime = app.startDateAndTime;
            apneaDetectorOutput.timeSignalDateTime = app.timeSignalDateTime;
            
            app.apneaDetectorWindow.Visible = 'off';
            [recordPath, recordName] = fileparts(app.filespec);
            filename = strcat(recordPath, filesep, recordName, '_', app.targetSignal, "_apneaDetection_", strrep(app.version, '.', '_'), '.mat');
            uisave('apneaDetectorOutput', filename) 
            app.apneaDetectorWindow.Visible = 'on'; 
            
        end

        % Menu selected function: OpenHelpMenu
        function HelpMenuSelected(app, event)
            
        % take readers to the git repository
        web('https://github.com/jessePfammatter/apneaDetector/blob/master/apneaDetector_Help.md')
        
        end

        % Menu selected function: ReturntoDefaultMenu_2
        function ReturntoDefaultMenu_2Selected(app, event)
                        
            % save the value to the app
            app.PSPMultiplierEditField.Value = app.PSPMultiplierDefualt;
            app.tunableParameters.postSighPlusDurationMultiplier = app.PSPMultiplierDefualt;
            
            % now rerun app with new default value
            DetectApneasandSleepMenuSelected(app)
            
             
        end

        % Value changed function: PSPMultiplierEditField
        function PSPMultiplierEditFieldValueChanged(app, event)
                        
            % add a caution window that tells the user this will erase all notes and re-run the analysis fresh
            selection = uiconfirm(app.apneaDetectorWindow, 'Warning! Changing this value will NOT restart the analysis. Please select Detect Apneas after you have changed all appropriate settings. Do you want to proceed?', 'Change PSP Value?');
            
            if strcmp(selection, 'OK') 
                % now rerun app with new value
                app.tunableParameters.postSighPlusDurationMultiplier = app.PSPMultiplierEditField.Value;
            else
                % return the PSP multiplier back to what it was previously
                if isfield(app.tunableParameters, 'postSighPlusDurationMultiplier')
                    app.PSPMultiplierEditField.Value = app.tunableParameters.postSighPlusDurationMultiplier;            
                else
                    app.PSPMultiplierEditField.Value = app.PSPMultiplierDefualt;
                end
            end

        end

        % Value changed function: SighAmpThreshEditField
        function SighAmpThreshEditFieldValueChanged(app, event)
            
            % display dialog box warning
            selection = uiconfirm(app.apneaDetectorWindow, 'Warning! Changing this value will NOT restart the analysis. Please select Detect Apneas after you have changed all appropriate settings. Do you want to proceed?', 'Change PSP Value?');
            if strcmp(selection, 'OK') 
            
                app.tunableParameters.sighsStdMultiplier = str2num(app.SighAmpThreshEditField.Value);
                
            else
                %return to default or previous value?
                if isfield(app.tunableParameters, 'sighsStdMultiplier')
                    app.SighAmpThreshEditField.Value = app.tunableParameters.sighsStdMultiplier;            
                else
                    app.SighAmpThreshEditField.Value = app.SighAmpThreshDefault;
                end
                
            end
        end

        % Menu selected function: ReturntoDefaultMenu
        function ReturntoDefaultMenuSelected(app, event)
            app.SighAmpThreshEditField.Value = app.SighAmpThreshDefault;
            app.tunableParameters.sighsStdMultiplier = app.SighAmpThreshDefault;
        end

        % Menu selected function: ShowPostSighPlusDurationMenu
        function ShowPostSighPlusDurationMenuSelected(app, event)
            
            % toggle check mark
            if strcmp(app.ShowPostSighPlusDurationMenu.Checked, 'on')
                app.ShowPostSighPlusDurationMenu.Checked = 'off';
            else
                app.ShowPostSighPlusDurationMenu.Checked = 'on';
            end
            
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);
        end

        % Menu selected function: ShowRawSignalOverlayMenu
        function ShowRawSignalOverlayMenuSelected(app, event)
            
            % toggle check mark
            if strcmp(app.ShowRawSignalOverlayMenu.Checked, 'on')
                app.ShowRawSignalOverlayMenu.Checked = 'off';
            else
                app.ShowRawSignalOverlayMenu.Checked = 'on';
            end
            
            % delete the old markers for specified events.
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);
            delete(app.tempMarkerEventInspector);

            % clear the plot window and replot
            cla(app.eventInspectorPlotWindow)
            plotEventInspector(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.apneaDetectorWindow = uifigure('Visible', 'off');
            app.apneaDetectorWindow.Name = 'apneaDetector';
            app.FileMenu = uimenu(app.apneaDetectorWindow);
            app.FileMenu.Text = 'File';
            app.LoadEDFMenu = uimenu(app.FileMenu);
            app.LoadEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @openFile, true);
            app.LoadEDFMenu.Text = 'Load EDF';
            app.DetectApneasandSleepMenu = uimenu(app.FileMenu);
            app.DetectApneasandSleepMenu.MenuSelectedFcn = createCallbackFcn(app, @DetectApneasandSleepMenuSelected, true);
            app.DetectApneasandSleepMenu.Text = 'Detect Apneas and Sleep';
            app.LoadHumanApneaDetectionMenu = uimenu(app.FileMenu);
            app.LoadHumanApneaDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadHumanApneaDetectionMenuSelected, true);
            app.LoadHumanApneaDetectionMenu.Enable = 'off';
            app.LoadHumanApneaDetectionMenu.Text = 'Load Human Apnea Detection';
            app.LoadHumanSleepScoringMenu = uimenu(app.FileMenu);
            app.LoadHumanSleepScoringMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadHumanSleepScoringMenuSelected, true);
            app.LoadHumanSleepScoringMenu.Enable = 'off';
            app.LoadHumanSleepScoringMenu.Text = 'Load Human Sleep Scoring';
            app.SaveAnalysisStateMenu = uimenu(app.FileMenu);
            app.SaveAnalysisStateMenu.Visible = 'off';
            app.SaveAnalysisStateMenu.Enable = 'off';
            app.SaveAnalysisStateMenu.Text = 'Save Analysis State';
            app.LoadPreviouslyAnalyzedEDFMenu = uimenu(app.FileMenu);
            app.LoadPreviouslyAnalyzedEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPreviouslyAnalyzedEDFMenuSelected, true);
            app.LoadPreviouslyAnalyzedEDFMenu.Text = 'Load Previously Analyzed EDF';
            app.ExportDatatomatFileMenu = uimenu(app.FileMenu);
            app.ExportDatatomatFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatomatFileMenuSelected, true);
            app.ExportDatatomatFileMenu.Enable = 'off';
            app.ExportDatatomatFileMenu.Text = 'Export Data to .mat File';
            app.ExportDatatoSpreadsheetMenu = uimenu(app.FileMenu);
            app.ExportDatatoSpreadsheetMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoSpreadsheetMenuSelected, true);
            app.ExportDatatoSpreadsheetMenu.Text = 'Export Data to Spreadsheet';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.SettingsMenu = uimenu(app.apneaDetectorWindow);
            app.SettingsMenu.Text = 'Settings';
            app.DisplaySettingsMenu = uimenu(app.SettingsMenu);
            app.DisplaySettingsMenu.Text = 'Display Settings';
            app.UnfilteredSignalYLimitsMenu = uimenu(app.DisplaySettingsMenu);
            app.UnfilteredSignalYLimitsMenu.Text = 'Unfiltered Signal Y Limits';
            app.incrementStd = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.incrementStd.MenuSelectedFcn = createCallbackFcn(app, @incrementStdSelected, true);
            app.incrementStd.Text = '+1 Std';
            app.decrementStd = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.decrementStd.MenuSelectedFcn = createCallbackFcn(app, @decrementStdMenuSelected, true);
            app.decrementStd.Text = '-1 Std';
            app.Default4StdMenu = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.Default4StdMenu.MenuSelectedFcn = createCallbackFcn(app, @Default4StdMenuSelected, true);
            app.Default4StdMenu.Text = 'Default (4 Std)';
            app.displayEventMarkersMenu = uimenu(app.DisplaySettingsMenu);
            app.displayEventMarkersMenu.MenuSelectedFcn = createCallbackFcn(app, @displayEventMarkersMenuSelected, true);
            app.displayEventMarkersMenu.Checked = 'on';
            app.displayEventMarkersMenu.Text = 'Display Event Markers';
            app.RestoreDisplaySettingstoDefaultMenu = uimenu(app.DisplaySettingsMenu);
            app.RestoreDisplaySettingstoDefaultMenu.Visible = 'off';
            app.RestoreDisplaySettingstoDefaultMenu.Text = 'Restore Display Settings to Default';
            app.EventInspectorSettingsMenu = uimenu(app.DisplaySettingsMenu);
            app.EventInspectorSettingsMenu.Text = 'Event Inspector Settings';
            app.ShowPostSighPlusDurationMenu = uimenu(app.EventInspectorSettingsMenu);
            app.ShowPostSighPlusDurationMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowPostSighPlusDurationMenuSelected, true);
            app.ShowPostSighPlusDurationMenu.Text = 'Show Post Sigh Plus Duration';
            app.ShowRawSignalOverlayMenu = uimenu(app.EventInspectorSettingsMenu);
            app.ShowRawSignalOverlayMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowRawSignalOverlayMenuSelected, true);
            app.ShowRawSignalOverlayMenu.Text = 'Show Raw Signal Overlay';
            app.SleepDetectionSettingsMenu = uimenu(app.SettingsMenu);
            app.SleepDetectionSettingsMenu.Text = 'Sleep Detection Settings';
            app.EpochDurationMenu = uimenu(app.SleepDetectionSettingsMenu);
            app.EpochDurationMenu.Visible = 'off';
            app.EpochDurationMenu.Enable = 'off';
            app.EpochDurationMenu.Text = 'Epoch Duration';
            app.SetEpochDurationMenu = uimenu(app.EpochDurationMenu);
            app.SetEpochDurationMenu.Visible = 'off';
            app.SetEpochDurationMenu.Text = 'Set Epoch Duration';
            app.Default4sMenu = uimenu(app.EpochDurationMenu);
            app.Default4sMenu.Visible = 'off';
            app.Default4sMenu.Text = 'Default (4s)';
            app.MinimumWakeDurationTimeMenu = uimenu(app.SleepDetectionSettingsMenu);
            app.MinimumWakeDurationTimeMenu.Visible = 'off';
            app.MinimumWakeDurationTimeMenu.Text = 'Minimum Wake Duration Time';
            app.SignalSmoothingDurationMenu = uimenu(app.SleepDetectionSettingsMenu);
            app.SignalSmoothingDurationMenu.Visible = 'off';
            app.SignalSmoothingDurationMenu.Enable = 'off';
            app.SignalSmoothingDurationMenu.Text = 'Signal Smoothing Duration';
            app.Menu = uimenu(app.SignalSmoothingDurationMenu);
            app.Menu.Visible = 'off';
            app.Menu.Text = 'Menu';
            app.SignalVariabililityThresholdMultiplierMenu = uimenu(app.SleepDetectionSettingsMenu);
            app.SignalVariabililityThresholdMultiplierMenu.Visible = 'off';
            app.SignalVariabililityThresholdMultiplierMenu.Text = 'Signal Variabilility Threshold Multiplier';
            app.RestoreSleepDetectionSettingstoDefaultMenu = uimenu(app.SleepDetectionSettingsMenu);
            app.RestoreSleepDetectionSettingstoDefaultMenu.Visible = 'off';
            app.RestoreSleepDetectionSettingstoDefaultMenu.Text = 'Restore Sleep Detection Settings to Default';
            app.UseHumanSleepScoringforApneaDetectionMenu = uimenu(app.SleepDetectionSettingsMenu);
            app.UseHumanSleepScoringforApneaDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @UseHumanSleepScoringforApneaDetectionMenuSelected, true);
            app.UseHumanSleepScoringforApneaDetectionMenu.Text = 'Use Human Sleep Scoring for Apnea Detection';
            app.SighDetectionSettingsMenu = uimenu(app.SettingsMenu);
            app.SighDetectionSettingsMenu.Text = 'Sigh Detection Settings';
            app.SighTidalVolumeThresholdMenu = uimenu(app.SighDetectionSettingsMenu);
            app.SighTidalVolumeThresholdMenu.Visible = 'off';
            app.SighTidalVolumeThresholdMenu.Enable = 'off';
            app.SighTidalVolumeThresholdMenu.Text = 'Sigh Tidal Volume Threshold';
            app.StdMenu_3 = uimenu(app.SighTidalVolumeThresholdMenu);
            app.StdMenu_3.Text = '+1 Std';
            app.StdMenu_4 = uimenu(app.SighTidalVolumeThresholdMenu);
            app.StdMenu_4.Text = '-1 Std';
            app.Default4stdMenu_2 = uimenu(app.SighTidalVolumeThresholdMenu);
            app.Default4stdMenu_2.Text = 'Default (4 std)';
            app.PostSighPlusDurationMultiplierMenu = uimenu(app.SighDetectionSettingsMenu);
            app.PostSighPlusDurationMultiplierMenu.Text = 'Post Sigh Plus Duration Multiplier';
            app.AdjustPSPMultiplierinEventInspectorBelowMenu = uimenu(app.PostSighPlusDurationMultiplierMenu);
            app.AdjustPSPMultiplierinEventInspectorBelowMenu.Enable = 'off';
            app.AdjustPSPMultiplierinEventInspectorBelowMenu.Text = 'Adjust PSP Multiplier in Event Inspector Below';
            app.ReturntoDefaultMenu_2 = uimenu(app.PostSighPlusDurationMultiplierMenu);
            app.ReturntoDefaultMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ReturntoDefaultMenu_2Selected, true);
            app.ReturntoDefaultMenu_2.Text = 'Return to Default';
            app.SighAmplitudeThresholdMenu = uimenu(app.SighDetectionSettingsMenu);
            app.SighAmplitudeThresholdMenu.Text = 'Sigh Amplitude Threshold';
            app.AdjustSighAmpThreshinEventInspectorBelowMenu = uimenu(app.SighAmplitudeThresholdMenu);
            app.AdjustSighAmpThreshinEventInspectorBelowMenu.Enable = 'off';
            app.AdjustSighAmpThreshinEventInspectorBelowMenu.Text = 'Adjust Sigh Amp Thresh in Event Inspector Below';
            app.ReturntoDefaultMenu = uimenu(app.SighAmplitudeThresholdMenu);
            app.ReturntoDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ReturntoDefaultMenuSelected, true);
            app.ReturntoDefaultMenu.Text = 'Return to Default';
            app.HelpMenu = uimenu(app.apneaDetectorWindow);
            app.HelpMenu.Text = 'Help';
            app.OpenHelpMenu = uimenu(app.HelpMenu);
            app.OpenHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.OpenHelpMenu.Text = 'Open Help';
            app.AboutMenu = uimenu(app.apneaDetectorWindow);
            app.AboutMenu.Text = 'About';
            app.httpsgithubcomjessePfammatterapneaDetectorMenu = uimenu(app.AboutMenu);
            app.httpsgithubcomjessePfammatterapneaDetectorMenu.MenuSelectedFcn = createCallbackFcn(app, @httpsgithubcomjessePfammatterapneaDetectorMenuSelected, true);
            app.httpsgithubcomjessePfammatterapneaDetectorMenu.Enable = 'off';
            app.httpsgithubcomjessePfammatterapneaDetectorMenu.Text = 'https://github.com/jessePfammatter/apneaDetector';
            app.VersionMenu_2 = uimenu(app.AboutMenu);
            app.VersionMenu_2.Enable = 'off';
            app.VersionMenu_2.Text = 'Version 0.20';
            app.SummaryMenu = uimenu(app.apneaDetectorWindow);
            app.SummaryMenu.Visible = 'off';
            app.SummaryMenu.Enable = 'off';
            app.SummaryMenu.Text = 'Summary';
            app.OutputApneaStatsMenu = uimenu(app.SummaryMenu);
            app.OutputApneaStatsMenu.Text = 'Output Apnea Stats';
            app.filteredSignalWindow = uiaxes(app.apneaDetectorWindow);
            title(app.filteredSignalWindow, 'Filtered Signal and Apnea Detection')
            xlabel(app.filteredSignalWindow, 'Time of Day')
            ylabel(app.filteredSignalWindow, 'Norm. Pleth Amplitude')
            app.eventInspectorPlotWindow = uiaxes(app.apneaDetectorWindow);
            title(app.eventInspectorPlotWindow, 'Event Inspector')
            xlabel(app.eventInspectorPlotWindow, 'Time of Day')
            ylabel(app.eventInspectorPlotWindow, 'Norm. Pleth Amlitdue')
            app.supportingPlot1 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot1, '')
            xlabel(app.supportingPlot1, '')
            ylabel(app.supportingPlot1, '')
            app.PlotLabel = uilabel(app.apneaDetectorWindow);
            app.PlotLabel.Text = 'Plot:';
            app.supportingPlot1Dropdown = uidropdown(app.apneaDetectorWindow);
            app.supportingPlot1Dropdown.ValueChangedFcn = createCallbackFcn(app, @supportingPlot1DropdownValueChanged, true);
            app.supportingPlot2 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot2, '')
            xlabel(app.supportingPlot2, '')
            ylabel(app.supportingPlot2, '')
            app.PlotLabel_2 = uilabel(app.apneaDetectorWindow);
            app.PlotLabel_2.Text = 'Plot:';
            app.supportingPlot2Dropdown = uidropdown(app.apneaDetectorWindow);
            app.supportingPlot2Dropdown.ValueChangedFcn = createCallbackFcn(app, @supportingPlot2DropdownValueChanged, true);
            app.supportingPlot3 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot3, '')
            xlabel(app.supportingPlot3, '')
            ylabel(app.supportingPlot3, '')
            app.PlotLabel_3 = uilabel(app.apneaDetectorWindow);
            app.PlotLabel_3.Text = 'Plot:';
            app.unfilteredSignalWindow = uiaxes(app.apneaDetectorWindow);
            title(app.unfilteredSignalWindow, 'Unfiltered Signal')
            xlabel(app.unfilteredSignalWindow, 'Time of Day')
            ylabel(app.unfilteredSignalWindow, 'Norm. Pleth Amp.')
            app.supportingPlot3Dropdown = uidropdown(app.apneaDetectorWindow);
            app.supportingPlot3Dropdown.ValueChangedFcn = createCallbackFcn(app, @supportingPlot3DropdownValueChanged, true);
            app.breathClusteringPlot = uiaxes(app.apneaDetectorWindow);
            title(app.breathClusteringPlot, 'Breath Clustering')
            xlabel(app.breathClusteringPlot, 'Inter-event Interval (s)')
            ylabel(app.breathClusteringPlot, 'Breath Amplitdues')
            app.ManualApneaAdjustButtonGroup = uibuttongroup(app.apneaDetectorWindow);
            app.ManualApneaAdjustButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ManualApneaAdjustButtonGroupSelectionChanged, true);
            app.ManualApneaAdjustButtonGroup.Title = 'Manual Apnea Adjust';
            app.SpontaneousButton = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.SpontaneousButton.Text = 'Spontaneous';
            app.SpontaneousButton.Value = true;
            app.PostSighButton = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.PostSighButton.Text = 'Post Sigh';
            app.PostSighPlusButton = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.PostSighPlusButton.Text = 'Post Sigh Plus';
            app.NotEventButton = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.NotEventButton.Text = 'Not Event';
            app.UnadjustedButton = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.UnadjustedButton.Text = 'Unadjusted';
            app.ofTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.ofTextAreaLabel.Text = 'of';
            app.totalNumberOfApneasText = uitextarea(app.apneaDetectorWindow);
            app.totalNumberOfApneasText.Editable = 'off';
            app.filepathDisplayEDF = uitextarea(app.apneaDetectorWindow);
            app.filepathDisplayEDF.Value = {'EDF Filepath:'};
            app.SelectPlethChannelDropDown = uidropdown(app.apneaDetectorWindow);
            app.SelectPlethChannelDropDown.Items = {'Ch 1', 'Ch 2', 'Ch 3'};
            app.SelectPlethChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPlethChannelDropDownValueChanged, true);
            app.SelectPlethChannelDropDown.Visible = 'off';
            app.SelectPlethChannelDropDown.Value = 'Ch 1';
            app.EventSpinnerLabel = uilabel(app.apneaDetectorWindow);
            app.EventSpinnerLabel.Text = 'Event #';
            app.EventSpinner = uispinner(app.apneaDetectorWindow);
            app.EventSpinner.ValueChangedFcn = createCallbackFcn(app, @EventSpinnerValueChanged, true);
            app.EventNotesDoNotUseReturnKeyTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.EventNotesDoNotUseReturnKeyTextAreaLabel.Text = 'Event Notes (Do Not Use Return Key)';
            app.EventNotesDoNotUseReturnKeyTextArea = uitextarea(app.apneaDetectorWindow);
            app.EventNotesDoNotUseReturnKeyTextArea.ValueChangedFcn = createCallbackFcn(app, @EventNotesDoNotUseReturnKeyTextAreaValueChanged, true);
            app.SessionNotesDoNotUseReturnKeyTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.SessionNotesDoNotUseReturnKeyTextAreaLabel.Text = 'Session Notes (Do Not Use Return Key)';
            app.SessionNotesDoNotUseReturnKeyTextArea = uitextarea(app.apneaDetectorWindow);
            app.SessionNotesDoNotUseReturnKeyTextArea.ValueChangedFcn = createCallbackFcn(app, @SessionNotesDoNotUseReturnKeyTextAreaValueChanged, true);
            app.SessionNotesDoNotUseReturnKeyTextArea.Value = {'Penny for your thoughts?  Who''s scoring? Etc.'};
            app.zoomOutEventInspectorButton = uibutton(app.apneaDetectorWindow, 'push');
            app.zoomOutEventInspectorButton.ButtonPushedFcn = createCallbackFcn(app, @zoomOutEventInspectorButtonPushed, true);
            app.zoomOutEventInspectorButton.Text = '-';
            app.zoomInEventInspectorButton = uibutton(app.apneaDetectorWindow, 'push');
            app.zoomInEventInspectorButton.ButtonPushedFcn = createCallbackFcn(app, @zoomInEventInspectorButtonPushed, true);
            app.zoomInEventInspectorButton.Text = '+';
            app.DetectApneasShortcut = uibutton(app.apneaDetectorWindow, 'push');
            app.DetectApneasShortcut.ButtonPushedFcn = createCallbackFcn(app, @DetectApneasandSleepMenuSelected, true);
            app.DetectApneasShortcut.Visible = 'off';
            app.DetectApneasShortcut.Text = 'Detect Apneas';
            app.LoadSignalShortcut = uibutton(app.apneaDetectorWindow, 'push');
            app.LoadSignalShortcut.ButtonPushedFcn = createCallbackFcn(app, @openFile, true);
            app.LoadSignalShortcut.Text = 'Load Signal';
            app.ApneaTypeLabel = uilabel(app.apneaDetectorWindow);
            app.ApneaTypeLabel.Text = 'Apnea Type';
            app.AutoApneaTypeLabel = uilabel(app.apneaDetectorWindow);
            app.AutoApneaTypeLabel.Text = 'Auto Apnea Type';
            app.ExploreSignalButton = uibutton(app.apneaDetectorWindow, 'push');
            app.ExploreSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreSignalButtonPushed, true);
            app.ExploreSignalButton.Visible = 'off';
            app.ExploreSignalButton.Text = 'Explore Signal';
            app.filepathDisplaySleepScore = uitextarea(app.apneaDetectorWindow);
            app.filepathDisplaySleepScore.Value = {'Human Sleep Score Filepath:'};
            app.filepathDisplayApneaScore = uitextarea(app.apneaDetectorWindow);
            app.filepathDisplayApneaScore.Value = {'Human Apnea Score Filepath:'};
            app.PSPMultiplierEditFieldLabel = uilabel(app.apneaDetectorWindow);
            app.PSPMultiplierEditFieldLabel.Enable = 'off';
            app.PSPMultiplierEditFieldLabel.Text = 'PSP Multiplier';
            app.PSPMultiplierEditField = uieditfield(app.apneaDetectorWindow, 'numeric');
            app.PSPMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @PSPMultiplierEditFieldValueChanged, true);
            app.PSPMultiplierEditField.Enable = 'off';
            app.SighAmpThreshEditFieldLabel = uilabel(app.apneaDetectorWindow);
            app.SighAmpThreshEditFieldLabel.Text = 'Sigh Amp Thresh';
            app.SighAmpThreshEditField = uieditfield(app.apneaDetectorWindow, 'text');
            app.SighAmpThreshEditField.ValueChangedFcn = createCallbackFcn(app, @SighAmpThreshEditFieldValueChanged, true);
            app.SighAmpThreshEditField.Value = '0';
            % Show the figure after all components are created
            app.apneaDetectorWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apneaDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.apneaDetectorWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.apneaDetectorWindow)
        end
    end
end